classdef aljazDRAWapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIAxes               matlab.ui.control.UIAxes
        PlotListBoxLabel     matlab.ui.control.Label
        PlotListBox          matlab.ui.control.ListBox
        SubjectSpinnerLabel  matlab.ui.control.Label
        SubjectSpinner       matlab.ui.control.Spinner
        TargetSpinnerLabel   matlab.ui.control.Label
        TargetSpinner        matlab.ui.control.Spinner
        UIAxes2              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        hap % Description
    end
    
    methods (Access = private)
        
        function plotStuff(app)
            %load('haptic_data.mat');
            %haptic_data=app.hap;
            damping_no=3;
            value = app.PlotListBox.Value;
            Wn = 20/100;                   % Normalized cutoff frequency
            [Fb,Fa]=butter(4,Wn);
            subject_no=app.SubjectSpinner.Value;
            target_no=app.TargetSpinner.Value;
            if value=="Acc Porazdelitev"
                
                
                idxStart=1+(target_no-1)*200;
                idxEnd=idxStart+199;
                
                trajY1=app.hap(subject_no).damping(damping_no).position_y_path(1).U(idxStart:idxEnd);
                trajZ1=app.hap(subject_no).damping(damping_no).position_z_path(1).V(idxStart:idxEnd);
                velY1=diff(trajY1);
                velZ1=diff(trajZ1);
                accY1=diff(velY1);
                accZ1=diff(velZ1);
                
                trajY2=app.hap(subject_no).damping(damping_no).position_y_path(2).U(idxStart:idxEnd);
                trajZ2=app.hap(subject_no).damping(damping_no).position_z_path(2).V(idxStart:idxEnd);
                velY2=diff(trajY2);
                velZ2=diff(trajZ2);
                accY2=diff(velY2);
                accZ2=diff(velZ2);
                
                accY1f=filter(Fb,Fa,accY1);
                accZ1f=filter(Fb,Fa,accZ1);
                accY2f=filter(Fb,Fa,accY2);
                accZ2f=filter(Fb,Fa,accZ2);
                
                %figure()
                %hold on
                plot(app.UIAxes,[1:length(accY1f)],accY1f,[1:length(accY2f)],accY2f);
                title(app.UIAxes,"acc Y");
                legend(app.UIAxes,'repetition 1','repetition 2')
                plot(app.UIAxes2,[1:length(accZ1f)],accZ1f,[1:length(accZ2f)],accZ2f);
                title(app.UIAxes2,"acc Z");
                legend(app.UIAxes2,'repetition 1','repetition 2')
                %hold off
                
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.hap=load("haptic_data.mat");
            plotStuff(app);
            
        end

        % Value changed function: PlotListBox
        function PlotListBoxValueChanged(app, event)
            plotStuff(app);
        end

        % Value changed function: SubjectSpinner
        function SubjectSpinnerValueChanged(app, event)
            plotStuff(app);
        end

        % Value changed function: TargetSpinner
        function TargetSpinnerValueChanged(app, event)
            plotStuff(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PlotListBoxLabel = uilabel(app.UIFigure);
            app.PlotListBoxLabel.Text = 'Plot';
            app.PlotListBox = uilistbox(app.UIFigure);
            app.PlotListBox.Items = {'Acc Porazdelitev', 'Item 2', 'Item 3', 'Item 4', 'Item 5'};
            app.PlotListBox.ValueChangedFcn = createCallbackFcn(app, @PlotListBoxValueChanged, true);
            app.PlotListBox.Value = 'Acc Porazdelitev';
            app.SubjectSpinnerLabel = uilabel(app.UIFigure);
            app.SubjectSpinnerLabel.Text = 'Subject';
            app.SubjectSpinner = uispinner(app.UIFigure);
            app.SubjectSpinner.Limits = [1 16];
            app.SubjectSpinner.ValueChangedFcn = createCallbackFcn(app, @SubjectSpinnerValueChanged, true);
            app.SubjectSpinner.Value = 1;
            app.TargetSpinnerLabel = uilabel(app.UIFigure);
            app.TargetSpinnerLabel.Text = 'Target';
            app.TargetSpinner = uispinner(app.UIFigure);
            app.TargetSpinner.Limits = [1 16];
            app.TargetSpinner.ValueChangedFcn = createCallbackFcn(app, @TargetSpinnerValueChanged, true);
            app.TargetSpinner.Value = 1;
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aljazDRAWapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end